classdef waiting_room < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        StartGameButton   matlab.ui.control.Button
        Label             matlab.ui.control.Label
        PlayerCountLabel  matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
    end

    
    properties (Access = private)
        g; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.g=game();
            temp=game.getCurrentValuesS();
            while(temp(5)~=1)
                temp=game.getCurrentValuesS();
                app.Label.Text=temp(6)+"";
            end
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            temp=game.getCurrentValuesS();
            thingSpeakWrite(1302152,[2,1,1,1,1,1,1],'WriteKey','NGW99VVOQPSVSISN')
            thingSpeakWrite(1302961,[0,0,0,0,1,temp(6)],'WriteKey','5PMM9O7QNFYBH0OH')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.PlayerCountLabel = uilabel(app.UIFigure);
            app.PlayerCountLabel.Text = 'Player Count:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waiting_room

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end